classdef MovieViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        OpenMenu          matlab.ui.container.Menu
        UIAxes            matlab.ui.control.UIAxes
        FrameSliderLabel  matlab.ui.control.Label
        FrameSlider       matlab.ui.control.Slider
    end


    properties (Access = private)
        location    % 'local' or 'remote'.
        mouse
        date
        expnum
        sesDir
        mov
        frameInfo
    end

    methods (Access = public)
    
        function sessionChosen(app, callingApp)
            if callingApp.cancelled
                delete(callingApp)
                return
            end
            location = callingApp.LocationDropDown.Value;
            mouse = callingApp.MouseDropDown.Value;
            date = callingApp.DateDropDown.Value;
            expnum = callingApp.SessionDropDown.Value;
            sesDir = sessiondir(mouse, date, expnum, location);
            frameArgs = callingApp.frameArgs;
            
            % Load tiffs or raw file. To do: Add support for frames indexing
            % into raw movie file.
            if exist(fullfile(sesDir, 'mov'), 'dir')
                movDir = fullfile(sesDir, 'mov');
                if length(frameArgs) == 0
                    mov = tss_read(movDir);
                elseif length(frameArgs) == 1
                    mov = tss_read(movDir, frameArgs(1));
                else
                    mov = tss_read(movDir, frameArg(1), frameArgs(2));
                end
                
            else
                rawFile = fullfile(sesDir, 'Image_0001_0001.raw');
                if length(frameArgs) == 0
                    mov = raw_read(rawFile);
                elseif length(frameArgs) == 1
                    mov = raw_read(rawFile, frameArgs(1));
                else
                    mov = raw_read(rawFile, frameArg(1), frameArgs(2));
                end
            end

            delete(callingApp);
            app.location = location;
            app.mouse = mouse;
            app.date = date;
            app.expnum = expnum;
            app.sesDir = sesDir;
            app.mov = mov;
            
            % Load frame info if it exists.
            if exist(fullfile(app.sesDir, 'frameInfo.mat'), 'file')
                app.frameInfo = loadFrameInfo(app.mouse, app.date, ...
                                              app.expnum, app.location);
            end                  
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            OpenMovie(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            OpenMovie(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MovieViewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end